classdef Deep_NN_Assembler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeepNNAssemblerUIFigure   matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        LoadTrainDataMenu         matlab.ui.container.Menu
        LoadTestDataMenu          matlab.ui.container.Menu
        ExitMenu                  matlab.ui.container.Menu
        AboutMenu                 matlab.ui.container.Menu
        AboutMenu_2               matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        TrainingTab               matlab.ui.container.Tab
        AccuracyText              matlab.ui.control.Label
        ModelAccuracyLabel        matlab.ui.control.Label
        NoIterationsEditField     matlab.ui.control.NumericEditField
        NoIterationsLabel         matlab.ui.control.Label
        LearningRateEditField     matlab.ui.control.NumericEditField
        LearningRateLabel         matlab.ui.control.Label
        VisualizationPanel        matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        TestingTab                matlab.ui.container.Tab
        Label                     matlab.ui.control.Label
        ResultLabel               matlab.ui.control.Label
        ShowButton                matlab.ui.control.Button
        IndexEditField            matlab.ui.control.NumericEditField
        IndexEditFieldLabel       matlab.ui.control.Label
        DatasetDropDown           matlab.ui.control.DropDown
        DatasetDropDownLabel      matlab.ui.control.Label
        Catdisplay                matlab.ui.control.UIAxes
        AssembleButton            matlab.ui.control.Button
        TrainModelButton          matlab.ui.control.Button
        ExitButton                matlab.ui.control.Button
        LayerEditorPanel          matlab.ui.container.Panel
        ActivationSelectDropDown  matlab.ui.control.DropDown
        ActivationSelectLabel     matlab.ui.control.Label
        ApplyButton               matlab.ui.control.Button
        MinusLayer                matlab.ui.control.Button
        PlusLayer                 matlab.ui.control.Button
        LayerNumberDropDown       matlab.ui.control.DropDown
        LayerNoLabel              matlab.ui.control.Label
        Version10Label            matlab.ui.control.Label
        DeepNNAssemblerLabel      matlab.ui.control.Label
        MainTable                 matlab.ui.control.Table
    end

    
    properties (Access = public)
        train_set_x_orig
        train_set_y_orig
        test_set_x_orig
        test_set_y_orig
        classes
        parameters
        m
        X_train
        X_test
        Y_test
        Y_train
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            empty_MainTable = {'Layer 1','4','Tanh',[]; 'Output','1','Sigmoid',[]};
            app.MainTable.Data = empty_MainTable;
            app.MainTable.ColumnEditable(2) = true;
            app.MainTable.ColumnEditable(3) = false;
            app.MainTable.ColumnEditable(4) = false;
            app.MinusLayer.Enable = 'off';
            app.TrainModelButton.Enable = 'off';
            app.AssembleButton.Enable = 'off';
            app.UIAxes.XColor = [0.94,0.94,0.94];
            app.UIAxes.YColor = [0.94,0.94,0.94];

            app.DatasetDropDown.Enable = 'off';
            app.IndexEditField.Enable = 'off';
            app.ShowButton.Enable = 'off';
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % When press, add the selected activation function from the
            % drop down menu.
            Current_layer = app.LayerNumberDropDown.Value;
            Act_function = app.ActivationSelectDropDown.Value;
            % Current table info
            Table_info = app.MainTable.Data;
            exact_match_mask = strcmp(Table_info(:,1), Current_layer);
            exact_match_locations = find(exact_match_mask);
            % set activation function to layer
            app.MainTable.Data(exact_match_locations,3) = {Act_function};

        end

        % Button pushed function: PlusLayer
        function PlusLayerButtonPushed(app, event)
            % Add another layer row into the table.
            Table_info = app.MainTable.Data;
            new_size_of_table = size(Table_info,1);
            new_layer_ID = ['Layer ' num2str(new_size_of_table)];
            new_layer = {new_layer_ID, [], [], []};
            app.MainTable.Data = [Table_info(1:end-1,:) ; new_layer ;Table_info(end,:)];
            if size(app.MainTable.Data,1) > 2
                app.MinusLayer.Enable = 'on';
                if isempty(app.train_set_x_orig) == 0
                    app.AssembleButton.Enable = 'on';
                end
            end
            app.LayerNumberDropDown.Items = app.MainTable.Data(:,1);
        end

        % Button pushed function: MinusLayer
        function MinusLayerButtonPushed(app, event)
            % get the layer ID to delete
            layer_ID = app.LayerNumberDropDown.Value;
            Table_data = app.MainTable.Data(1:end-1,:);
            output_layer = app.MainTable.Data(end,:);
            ID_val = str2double(cell2num(regexp(layer_ID,'\d*','Match')));
            Table_data(ID_val,:) = [];
            for i = 1:size(Table_data,1)
                new_ID = {['Layer ' num2str(i)]};
                Table_data(i,1) = new_ID;
            end
            app.MainTable.Data = [Table_data;output_layer];
            app.LayerNumberDropDown.Items = app.MainTable.Data(:,1);
            if size(app.MainTable.Data,1) > 2
                app.MinusLayer.Enable = 'on';
            else
                app.MinusLayer.Enable = 'off';
                app.AssembleButton.Enable = 'off';
            end
        end

        % Menu selected function: LoadTrainDataMenu
        function LoadTrainDataMenuSelected(app, event)
            % Load X_train corresponding file.
            [file,path] = uigetfile('*.h5','TRAIN Data Set File');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                cd(path)
                File_data_x = h5read(file,'/train_set_x');
                app.train_set_x_orig = permute(File_data_x,[3 2 1 4]);

                app.train_set_y_orig = h5read(file,'/train_set_y')';
                app.DatasetDropDown.Enable = 'on';
                app.IndexEditField.Enable = 'on';
                app.ShowButton.Enable = 'on';

                Index = app.IndexEditField.Value;
                imshow(app.train_set_x_orig(:,:,:,Index),[0,255],'Parent',app.Catdisplay);
                y = app.train_set_y_orig(1,Index);
                if y == 0
                    app.Label.Text = 'Non Cat';
                else
                    app.Label.Text = 'Cat';
                end

                if size(app.MainTable.Data,1) > 1
                    app.AssembleButton.Enable = 'on';
                else
                    msgbox('To assemble a NN, please add more layers','Warning');
                end
            end
        end

        % Menu selected function: LoadTestDataMenu
        function LoadTestDataMenuSelected(app, event)
            % Load X_test corresponding file.
            [file,path] = uigetfile('*.h5','TEST Data Set File');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                cd(path)
                File_data_x = h5read(file,'/test_set_x');
                app.test_set_x_orig = permute(File_data_x,[3 2 1 4]);

                app.test_set_y_orig = h5read(file,'/test_set_y')';
                app.DatasetDropDown.Enable = 'on';
                app.IndexEditField.Enable = 'on';
                app.ShowButton.Enable = 'on';
                app.classes = h5read(file,'/list_classes');
            end
            
        end

        % Value changed function: LayerNumberDropDown
        function LayerNumberDropDownValueChanged(app, event)
            value = app.LayerNumberDropDown.Value;
            if strcmp(value,'Output')
                app.MinusLayer.Enable = 'off';
                app.ActivationSelectDropDown.Enable = 'off';
                app.ApplyButton.Enable = 'off';
            else
                app.MinusLayer.Enable = 'on';
                app.ActivationSelectDropDown.Enable = 'on';
                app.ApplyButton.Enable = 'on';
            end
        end

        % Button pushed function: AssembleButton
        function AssembleButtonPushed(app, event)
            %% Assemble the Deep Neural Network
            %  Flatten the x matrices
            Current_table = app.MainTable.Data;
            emptyCells_two = cellfun(@isempty,Current_table(:,2));
            emptyCells_three = cellfun(@isempty,Current_table(:,3));
            [a1,~] = find(emptyCells_two==1,2);
            [a2,~] = find(emptyCells_three==1,2);

            if isempty(a1) == 1 && isempty(a2) == 1 
                if isempty(app.train_set_x_orig) == 0
                    size_train = size(app.train_set_x_orig,1)*size(app.train_set_x_orig,2)*size(app.train_set_x_orig,3);
                    train_set_x_flatten = reshape(app.train_set_x_orig,[size_train,size(app.train_set_x_orig,4)]);
                    app.X_train = double(train_set_x_flatten / 255);
                end
                if isempty(app.test_set_x_orig) == 0
                    size_test = size(app.test_set_x_orig,1)*size(app.test_set_x_orig,2)*size(app.test_set_x_orig,3);
                    test_set_x_flatten = reshape(app.test_set_x_orig,[size_test,size(app.test_set_x_orig,4)]);
                    app.X_test = double(test_set_x_flatten / 255);
                else
                    msgbox('Please load the Test Dataset and re-assemble','Warning');
                end
                app.Y_train = double(app.train_set_y_orig);
                app.Y_test = double(app.test_set_y_orig);
                app.m = size(app.Y_train,2);
                % Assemble layers
                assembly_table = app.MainTable.Data;
                size_table = zeros(size(assembly_table,1),2);
                
                %% Get the table sizes for random initialize
                for i = 1:size(size_table,1)
                    size_table(i,1) = str2double(assembly_table{i,2});
                    if i == 1
                        size_table(i,2) = size(app.X_train,1);
                        app.MainTable.Data(i,4) = num2strcell((size_table(i,2) * size_table(i,1)) + size_table(i,1));
                    else
                        size_table(i,2) = str2double(assembly_table{i-1,2});
                        app.MainTable.Data(i,4) = num2strcell((size_table(i,2) * size_table(i,1)) + size_table(i,1));
                    end
                end
                %% Create random initalisation matrices
                A = [];
                Z = [];
                dZ = [];
                dW = [];
                db = [];
                for i = 1:size(size_table,1)
                    [W,b] = rand_init(size_table(i,1),size_table(i,2),app.m);
                    layer_id = ['Layer_' num2str(i)];
                    app.parameters.(layer_id).W = W;
                    app.parameters.(layer_id).b = b;
                    app.parameters.(layer_id).Z = Z;
                    app.parameters.(layer_id).A = A;
                    app.parameters.(layer_id).dZ = dZ;
                    app.parameters.(layer_id).dW = dW;
                    app.parameters.(layer_id).db = db;
                    app.parameters.(layer_id).activation = app.MainTable.Data{1,3};
                end
                app.TrainModelButton.Enable = 'on';
                msgbox('Model can now be trained.','Message');
            else
                msgbox('Table cannot be empty! Please fill the missing cells','Warning');
            end
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            % prepare figure for plot
            app.UIAxes.XColor = [0.15,0.15,0.15];
            app.UIAxes.YColor = [0.15,0.15,0.15];
            
            %Here we train the model
            learning_rate = app.LearningRateEditField.Value;
            iterations = app.NoIterationsEditField.Value;
            k = 1;

            % Wait Bar
            fig = app.DeepNNAssemblerUIFigure;
            d = uiprogressdlg(fig,'Title','Please Wait, Training Deep Neural Network...');
            d.Message = ('Training Deep Neural Network...');
            pause(0.5)
            
            for i = 1: iterations
                % Forward Propagation
                for j = 1:size(fieldnames(app.parameters),1)
                    if j == 1
                        layer_id = ['Layer_' num2str(j)];
                        app.parameters.(layer_id).Z = app.parameters.(layer_id).W * app.X_train + app.parameters.(layer_id).b;
                        switch app.parameters.(layer_id).activation
                        case 'Tanh'
                            app.parameters.(layer_id).A = tanh_f(app.parameters.(layer_id).Z);
                        case 'Sigmoid'
                            app.parameters.(layer_id).A = sigmoid_f(app.parameters.(layer_id).Z);
                        case 'ReLu'
                            app.parameters.(layer_id).A = ReLu_f(app.parameters.(layer_id).Z);
                        case 'Leaky ReLu'    
                            app.parameters.(layer_id).A = Leaky_ReLu_f(app.parameters.(layer_id).Z,0.01);
                        end
                    else
                        layer_id = ['Layer_' num2str(j)];
                        aft_layer_id = ['Layer_' num2str(j-1)];
                        app.parameters.(layer_id).Z = app.parameters.(layer_id).W * app.parameters.(aft_layer_id).A + app.parameters.(layer_id).b;
                        switch app.parameters.(layer_id).activation
                        case 'Tanh'
                            app.parameters.(layer_id).A = tanh_f(app.parameters.(layer_id).Z);
                        case 'Sigmoid'
                            app.parameters.(layer_id).A = sigmoid_f(app.parameters.(layer_id).Z);
                        case 'ReLu'
                            app.parameters.(layer_id).A = ReLu_f(app.parameters.(layer_id).Z);
                        case 'Leaky ReLu'    
                            app.parameters.(layer_id).A = Leaky_ReLu_f(app.parameters.(layer_id).Z,0.01);
                        end
                    end
                end
                %End of Forward Propagation

                % Compute Cost
                last_layer_id = ['Layer_' num2str(size(fieldnames(app.parameters),1))];
                [cost] = Compute_cost(app.parameters.(last_layer_id).A, app.Y_train,app.m);

                % Backpropagation.
                for j = flip(1:size(fieldnames(app.parameters),1)) %needs to be flip to go backwards
                    if j == size(fieldnames(app.parameters),1)
                        layer_id = ['Layer_' num2str(j)];
                        aft_layer_id = ['Layer_' num2str(j-1)];
                        % dZ = A[l] - Y
                        app.parameters.(layer_id).dZ = app.parameters.(layer_id).A - app.Y_train;
                        % dW = (1/m) * dZ[l] * A[l-1]
                        app.parameters.(layer_id).dW = (1/app.m) * (app.parameters.(layer_id).dZ * (app.parameters.(aft_layer_id).A)');
                        % db = (1/m) * dZ[l] 
                        app.parameters.(layer_id).db = (1/app.m) * repmat(sum(app.parameters.(layer_id).dZ,2),1,app.m);
                    elseif j == 1 
                        layer_id = ['Layer_' num2str(j)];
                        fwd_layer_id = ['Layer_' num2str(j+1)];
                        switch app.parameters.(layer_id).activation %Based on the activation layer, the derivative is selected
                        case 'Tanh'
                            % dZ = W[l+1] * dZ[l+1] * g'(Z[l])
                            app.parameters.(layer_id).dZ = (app.parameters.(fwd_layer_id).W' * app.parameters.(fwd_layer_id).dZ) .* tanh_back(app.parameters.(layer_id).A);
                            % dW = (1/m) * dZ[l] * A[0]
                            app.parameters.(layer_id).dW = (1/app.m) * (app.parameters.(layer_id).dZ * (app.X_train'));
                            % db = (1/m) * dZ[l] 
                            app.parameters.(layer_id).db = (1/app.m) * repmat(sum(app.parameters.(layer_id).dZ,2),1,app.m);
                        case 'Sigmoid'
                            % dZ = W[l+1] * dZ[l+1] * g'(Z[l])
                            app.parameters.(layer_id).dZ = (app.parameters.(fwd_layer_id).W' * app.parameters.(fwd_layer_id).dZ) .* sigmoid_back(app.parameters.(layer_id).A);
                            % dW = (1/m) * dZ[l] * A[0]
                            app.parameters.(layer_id).dW = (1/app.m) * (app.parameters.(layer_id).dZ * (app.X_train'));
                            % db = (1/m) * dZ[l] 
                            app.parameters.(layer_id).db = (1/app.m) * repmat(sum(app.parameters.(layer_id).dZ,2),1,app.m);
                        case 'ReLu'
                            app.parameters.(layer_id).dZ = (app.parameters.(fwd_layer_id).W' * app.parameters.(fwd_layer_id).dZ) .* ReLu_back(app.parameters.(layer_id).A);
                            % dW = (1/m) * dZ[l] * A[0]
                            app.parameters.(layer_id).dW = (1/app.m) * (app.parameters.(layer_id).dZ * (app.X_train'));
                            % db = (1/m) * dZ[l] 
                            app.parameters.(layer_id).db = (1/app.m) * repmat(sum(app.parameters.(layer_id).dZ,2),1,app.m);
                        case 'Leaky ReLu'    
                            app.parameters.(layer_id).dZ = (app.parameters.(fwd_layer_id).W' * app.parameters.(fwd_layer_id).dZ) .* Leaky_ReLu_back(app.parameters.(layer_id).A,0.01);
                            % dW = (1/m) * dZ[l] * A[0]
                            app.parameters.(layer_id).dW = (1/app.m) * (app.parameters.(layer_id).dZ * (app.X_train'));
                            % db = (1/m) * dZ[l] 
                            app.parameters.(layer_id).db = (1/app.m) * repmat(sum(app.parameters.(layer_id).dZ,2),1,app.m);
                        end
                    else
                        layer_id = ['Layer_' num2str(j)];
                        fwd_layer_id = ['Layer_' num2str(j+1)];
                        aft_layer_id = ['Layer_' num2str(j-1)];
                        switch app.parameters.(layer_id).activation %Based on the activation layer, the derivative is selected
                        case 'Tanh'
                            % dZ = W[l+1] * dZ[l+1] * g'(Z[l])
                            app.parameters.(layer_id).dZ = (app.parameters.(fwd_layer_id).W' * app.parameters.(fwd_layer_id).dZ) .* (1 - (app.parameters.(layer_id).A).^2);
                            % dW = (1/m) * dZ[l] * A[l-1]
                            app.parameters.(layer_id).dW = (1/app.m) * (app.parameters.(layer_id).dZ * (app.parameters.(aft_layer_id).A)');
                            % db = (1/m) * dZ[l] 
                            app.parameters.(layer_id).db = (1/app.m) * sum(app.parameters.(layer_id).dZ);
                        case 'Sigmoid'
                            % dZ = W[l+1] * dZ[l+1] * g'(Z[l])
                            app.parameters.(layer_id).dZ = (app.parameters.(fwd_layer_id).W' * app.parameters.(fwd_layer_id).dZ) .* ((app.parameters.(layer_id).A)*(1-app.parameters.(layer_id).A));
                            % dW = (1/m) * dZ[l] * A[l-1]
                            app.parameters.(layer_id).dW = (1/app.m) * (app.parameters.(layer_id).dZ * (app.parameters.(aft_layer_id).A)');
                            % db = (1/m) * dZ[l] 
                            app.parameters.(layer_id).db = (1/app.m) * sum(app.parameters.(layer_id).dZ);
                        case 'ReLu'
                            app.parameters.(layer_id).dZ = (app.parameters.(fwd_layer_id).W' * app.parameters.(fwd_layer_id).dZ);
                            % dW = (1/m) * dZ[l] * A[l-1]
                            app.parameters.(layer_id).dW = (1/app.m) * (app.parameters.(layer_id).dZ * (app.parameters.(aft_layer_id).A)');
                            % db = (1/m) * dZ[l] 
                            app.parameters.(layer_id).db = (1/app.m) * sum(app.parameters.(layer_id).dZ);
                        case 'Leaky ReLu'    
                            app.parameters.(layer_id).dZ = (app.parameters.(fwd_layer_id).W' * app.parameters.(fwd_layer_id).dZ);
                            % dW = (1/m) * dZ[l] * A[l-1]
                            app.parameters.(layer_id).dW = (1/app.m) * (app.parameters.(layer_id).dZ * (app.parameters.(aft_layer_id).A)');
                            % db = (1/m) * dZ[l] 
                            app.parameters.(layer_id).db = (1/app.m) * sum(app.parameters.(layer_id).dZ);
                        end
                    end
                end
                %End of Backpropagation

                % Update Parameters
                for j = 1:size(fieldnames(app.parameters),1)
                    layer_id = ['Layer_' num2str(j)];
                    app.parameters.(layer_id).W = app.parameters.(layer_id).W - (learning_rate * app.parameters.(layer_id).dW);
                    app.parameters.(layer_id).b = app.parameters.(layer_id).b - (learning_rate * app.parameters.(layer_id).db);
                end
                %End of update parameters

                if mod(i,100) == 0
                    iteration(k,1) = k;
                    costing(k,1) = real(cost);
                    k = k + 1;

                    app.UIAxes.XLabel.String = 'Iterations X 100';
                    app.UIAxes.YLabel.String = 'Cost Value';
                    plot(app.UIAxes,iteration,costing,"LineWidth",2)
                end
                complete = (i/iterations);
                d.Value = complete;
                if d.Value < 99.99
                    d.Message = ([num2str(d.Value * 100,'%.2f') '% Completed.']);
                end
            end
            d.Message = ('Training completed');
            pause(1)
            close(d)
            %End of iterative foor loop

            %Prediction
            % Forward Propagation
                for j = 1:size(fieldnames(app.parameters),1)
                    if j == 1
                        layer_id = ['Layer_' num2str(j)];
                        app.parameters.(layer_id).Z = app.parameters.(layer_id).W * app.X_train + app.parameters.(layer_id).b;
                        switch app.parameters.(layer_id).activation
                        case 'Tanh'
                            app.parameters.(layer_id).A = tanh_f(app.parameters.(layer_id).Z);
                        case 'Sigmoid'
                            app.parameters.(layer_id).A = sigmoid_f(app.parameters.(layer_id).Z);
                        case 'ReLu'
                            app.parameters.(layer_id).A = ReLu_f(app.parameters.(layer_id).Z);
                        case 'Leaky ReLu'    
                            app.parameters.(layer_id).A = Leaky_ReLu_f(app.parameters.(layer_id).Z,0.01);
                        end
                    else
                        layer_id = ['Layer_' num2str(j)];
                        aft_layer_id = ['Layer_' num2str(j-1)];
                        app.parameters.(layer_id).Z = app.parameters.(layer_id).W * app.parameters.(aft_layer_id).A + app.parameters.(layer_id).b;
                        switch app.parameters.(layer_id).activation
                        case 'Tanh'
                            app.parameters.(layer_id).A = tanh_f(app.parameters.(layer_id).Z);
                        case 'Sigmoid'
                            app.parameters.(layer_id).A = sigmoid_f(app.parameters.(layer_id).Z);
                        case 'ReLu'
                            app.parameters.(layer_id).A = ReLu_f(app.parameters.(layer_id).Z);
                        case 'Leaky ReLu'    
                            app.parameters.(layer_id).A = Leaky_ReLu_f(app.parameters.(layer_id).Z,0.01);
                        end
                    end
                end
                %End of Forward Propagation
                last_layer_id = ['Layer_' num2str(size(fieldnames(app.parameters),1))];
                Predictions = app.parameters.(last_layer_id).A;
                %% Accuracy
                Accu = (dot(app.Y_train,Predictions') + dot((1-app.Y_train),(1-Predictions'))) / size(app.Y_train,2) * 100;
                app.AccuracyText.Text = ([num2str(Accu,'%.2f') '%']);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.DeepNNAssemblerUIFigure)
        end

        % Menu selected function: AboutMenu_2
        function AboutMenu_2Selected(app, event)
            msgbox('Made by: Juan Sebastian Conde, PhD.','About');
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            dataset = app.DatasetDropDown.Value;
            switch dataset
                case 'Training'
                    Index = app.IndexEditField.Value;
                    if Index <= size(app.train_set_x_orig,4)
                        imshow(app.train_set_x_orig(:,:,:,Index),[0,255],'Parent',app.Catdisplay);
                        y = app.train_set_y_orig(1,Index);
                        if y == 0
                            app.Label.Text = 'Non Cat';
                        else
                            app.Label.Text = 'Cat';
                        end
                    else
                        msgbox(['Index out of bounds. Maximum Index lower than: ' num2str(size(app.train_set_x_orig,4))],'About');
                    end
                case 'Tesing'
                    if isempty(app.test_set_x_orig) == 0
                        Index = app.IndexEditField.Value;
                        if Index <= size(app.test_set_x_orig,4)
                            imshow(app.test_set_x_orig(:,:,:,Index),[0,255],'Parent',app.Catdisplay);
                            y = app.test_set_y_orig(1,Index);
                            if y == 0
                                app.Label.Text = 'Non Cat';
                            else
                                app.Label.Text = 'Cat';
                            end
                        else
                             msgbox(['Index out of bounds. Maximum Index lower than: ' num2str(size(app.test_set_x_orig,4))],'About');
                        end
                    else
                        msgbox('Please load the test Dataset to display','Warning');
                    end
            end   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeepNNAssemblerUIFigure = uifigure('Visible', 'off');
            app.DeepNNAssemblerUIFigure.Name = 'Deep NN Assembler';
            app.DeepNNAssemblerUIFigure.Icon = 'plus-sign.png';
            app.FileMenu = uimenu(app.DeepNNAssemblerUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadTrainDataMenu = uimenu(app.FileMenu);
            app.LoadTrainDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTrainDataMenuSelected, true);
            app.LoadTrainDataMenu.Text = 'Load Train Data';
            app.LoadTestDataMenu = uimenu(app.FileMenu);
            app.LoadTestDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTestDataMenuSelected, true);
            app.LoadTestDataMenu.Text = 'Load Test Data';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.AboutMenu = uimenu(app.DeepNNAssemblerUIFigure);
            app.AboutMenu.Text = 'About';
            app.AboutMenu_2 = uimenu(app.AboutMenu);
            app.AboutMenu_2.MenuSelectedFcn = createCallbackFcn(app, @AboutMenu_2Selected, true);
            app.AboutMenu_2.Text = 'About';
            app.MainTable = uitable(app.DeepNNAssemblerUIFigure);
            app.MainTable.ColumnName = {'Layer No.'; 'Nodes No.'; 'Activation g(z)'; 'No. Parameters'};
            app.MainTable.RowName = {};
            app.DeepNNAssemblerLabel = uilabel(app.DeepNNAssemblerUIFigure);
            app.DeepNNAssemblerLabel.Text = 'Deep NN Assembler';
            app.Version10Label = uilabel(app.DeepNNAssemblerUIFigure);
            app.Version10Label.Text = {'Version 1.0'; ''};
            app.LayerEditorPanel = uipanel(app.DeepNNAssemblerUIFigure);
            app.LayerEditorPanel.Title = 'Layer Editor';
            app.LayerNoLabel = uilabel(app.LayerEditorPanel);
            app.LayerNoLabel.Text = 'Layer Number:';
            app.LayerNumberDropDown = uidropdown(app.LayerEditorPanel);
            app.LayerNumberDropDown.Items = {'Layer 1'};
            app.LayerNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerNumberDropDownValueChanged, true);
            app.LayerNumberDropDown.Value = 'Layer 1';
            app.PlusLayer = uibutton(app.LayerEditorPanel, 'push');
            app.PlusLayer.ButtonPushedFcn = createCallbackFcn(app, @PlusLayerButtonPushed, true);
            app.PlusLayer.Text = '+';
            app.MinusLayer = uibutton(app.LayerEditorPanel, 'push');
            app.MinusLayer.ButtonPushedFcn = createCallbackFcn(app, @MinusLayerButtonPushed, true);
            app.MinusLayer.Text = '-';
            app.ApplyButton = uibutton(app.LayerEditorPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ActivationSelectLabel = uilabel(app.LayerEditorPanel);
            app.ActivationSelectLabel.Text = 'Activation Select:';
            app.ActivationSelectDropDown = uidropdown(app.LayerEditorPanel);
            app.ActivationSelectDropDown.Items = {'Tanh', 'ReLu', 'Leaky ReLu', 'Sigmoid'};
            app.ActivationSelectDropDown.Value = 'Tanh';
            app.ExitButton = uibutton(app.DeepNNAssemblerUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.TrainModelButton = uibutton(app.DeepNNAssemblerUIFigure, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            app.AssembleButton = uibutton(app.DeepNNAssemblerUIFigure, 'push');
            app.AssembleButton.ButtonPushedFcn = createCallbackFcn(app, @AssembleButtonPushed, true);
            app.AssembleButton.Text = 'Assemble';
            app.TabGroup = uitabgroup(app.DeepNNAssemblerUIFigure);
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.VisualizationPanel = uipanel(app.TrainingTab);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes = uiaxes(app.VisualizationPanel);
            app.LearningRateLabel = uilabel(app.TrainingTab);
            app.LearningRateLabel.Text = 'Learning Rate:';
            app.LearningRateEditField = uieditfield(app.TrainingTab, 'numeric');
            app.LearningRateEditField.ValueDisplayFormat = '%.3f';
            app.LearningRateEditField.Value = 0.05;
            app.NoIterationsLabel = uilabel(app.TrainingTab);
            app.NoIterationsLabel.Text = 'No. Iterations:';
            app.NoIterationsEditField = uieditfield(app.TrainingTab, 'numeric');
            app.NoIterationsEditField.ValueDisplayFormat = '%.0f';
            app.NoIterationsEditField.Value = 10000;
            app.ModelAccuracyLabel = uilabel(app.TrainingTab);
            app.ModelAccuracyLabel.Text = 'Model Accuracy:';
            app.AccuracyText = uilabel(app.TrainingTab);
            app.AccuracyText.Text = ' - ';
            app.TestingTab = uitab(app.TabGroup);
            app.TestingTab.Title = 'Testing';
            app.Catdisplay = uiaxes(app.TestingTab);
            app.DatasetDropDownLabel = uilabel(app.TestingTab);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.TestingTab);
            app.DatasetDropDown.Items = {'Training', 'Tesing'};
            app.DatasetDropDown.Value = 'Training';
            app.IndexEditFieldLabel = uilabel(app.TestingTab);
            app.IndexEditFieldLabel.Text = 'Index';
            app.IndexEditField = uieditfield(app.TestingTab, 'numeric');
            app.IndexEditField.Value = 1;
            app.ShowButton = uibutton(app.TestingTab, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.ResultLabel = uilabel(app.TestingTab);
            app.ResultLabel.Text = 'Result: ';
            app.Label = uilabel(app.TestingTab);
            app.Label.Text = ' - ';
            % Show the figure after all components are created
            app.DeepNNAssemblerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Deep_NN_Assembler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeepNNAssemblerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeepNNAssemblerUIFigure)
        end
    end
end